classdef TVKApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        SessionOptionsMenu           matlab.ui.container.Menu
        OpenSessionMenu              matlab.ui.container.Menu
        SaveSessionMenu              matlab.ui.container.Menu
        ReportOptionsMenu            matlab.ui.container.Menu
        ReportGeneratorOptionsMenu   matlab.ui.container.Menu
        rptReporterAPI               matlab.ui.container.Menu
        rptDefault                   matlab.ui.container.Menu
        TestOptionsMenu              matlab.ui.container.Menu
        CreateNewTestMenu            matlab.ui.container.Menu
        HelpMenu                     matlab.ui.container.Menu
        OpenUserGuideMenu            matlab.ui.container.Menu
        OpenQualityStatementsMenu    matlab.ui.container.Menu
        RunReportPanel               matlab.ui.container.Panel
        SelectedReportGeneratorOptionEditField  matlab.ui.control.EditField
        SelectedReportGeneratorOptionEditFieldLabel  matlab.ui.control.Label
        RunTestsandGenerateReportButton  matlab.ui.control.Button
        ReportFileEditField          matlab.ui.control.EditField
        ReportFileEditFieldLabel     matlab.ui.control.Label
        SelectedTestsTable           matlab.ui.control.Table
        TestSelectionPanel           matlab.ui.container.Panel
        ResultsFolderBrowseButton    matlab.ui.control.Button
        ResultsFolderEditField       matlab.ui.control.EditField
        ResultsFolderEditFieldLabel  matlab.ui.control.Label
        OpenSelectedTestsinEditorButton  matlab.ui.control.Button
        SelectAllTestsCheckBox       matlab.ui.control.CheckBox
        TestFolderTree               matlab.ui.container.Tree
        TestFolderBrowseButton       matlab.ui.control.Button
        TestFolderEditField          matlab.ui.control.EditField
        TestFolderEditFieldLabel     matlab.ui.control.Label
    end


    %% Public Properties
    properties (Access = public)
        TVKBase = tvk.TVKBase.empty() % Data model
    end %properties

    properties (Access = private)
        doWholeUpdate (1,1) logical = false
        SelectedNodes
        CurrentParentNodeText (:,1) string = ""
        CurrentChildrenNodeText (:,1) string = ""
        SelectAllTestsCBValue (1,1) double = 0
    end %properties

    %% Private Methods
    methods (Access = private)

        function update( app )

            if app.doWholeUpdate

                app.TestFolderEditField.Value = app.TVKBase.TestFolder;
                app.ReportFileEditField.Value = app.TVKBase.ReportFile;

                app.ResultsFolderEditField.Enable = "on";
                app.ResultsFolderEditFieldLabel.Enable = "on";
                app.ResultsFolderBrowseButton.Enable = "on";
                app.CreateNewTestMenu.Enable = "on";
                app.SaveSessionMenu.Enable = "on";

                app.ReportGeneratorOptionsMenu.Enable = "on";

                if isempty(ver('rptgen'))
                    app.rptReporterAPI.Enable = "off";
                else
                    app.rptReporterAPI.Enable = "on";
                end

                app.rptDefault.Enable = "on";

                app.updateTree();

            end %if

            app.ResultsFolderEditField.Value = app.TVKBase.ResultsFolder;
            app.ReportFileEditField.Value = app.TVKBase.ReportFile;

            % Select tree nodes
            app.TestFolderTree.SelectedNodes = app.SelectedNodes;

            if ~isempty(app.SelectedNodes)
                % Enable run tests button
                app.RunTestsandGenerateReportButton.Enable = "on";

                % Enable open selected tests button
                app.OpenSelectedTestsinEditorButton.Enable = "on";

                % Set test table
                app.SelectedTestsTable.Data = app.TVKBase.DetailedTestTable;
            else
                app.SelectedTestsTable.Data = [];
            end

            if app.TVKBase.EnableCustomReportGen
                app.SelectedReportGeneratorOptionEditField.Value = "MATLAB Report Generator";
            else
                app.SelectedReportGeneratorOptionEditField.Value = "Test Report Plugin (Core MATLAB)";
            end

            app.ReportFileEditField.Value = app.TVKBase.ReportFile;

            app.SelectAllTestsCheckBox.Value = app.SelectAllTestsCBValue;

        end %function

        function results = findAllNodes(app, inputTreeNode)
            % FINDALLNODES Recursively find all child nodes in input tree
            % node
            arguments
                app
                inputTreeNode (1,1) matlab.ui.container.TreeNode
            end

            childrenNodes = inputTreeNode.Children;
            % Base case for algorithm
            if isempty(childrenNodes)
                results = inputTreeNode;
                return
            end

            results = matlab.ui.container.TreeNode.empty();
            for ii = 1:numel(childrenNodes)

                results = [results; app.findAllNodes(childrenNodes(ii))]; %#ok<*AGROW>

            end %for

        end %function

        function updateTree(app)

            % Acquire Test List and Parent Folders
            testList = app.TVKBase.TestTable.TestList;
            parentFolders = app.TVKBase.TestTable.ParentList;

            % Clear Current Children in Tree
            tc = app.TestFolderTree.Children;
            delete(tc);

            % Add elements to the tree, if they don't exist
            uniqueparent = unique(parentFolders);
            for pp = 1:numel(uniqueparent)

                if  ~ismember(uniqueparent(pp), app.CurrentParentNodeText)
                    parenttree = uitreenode(app.TestFolderTree,'Text', uniqueparent(pp));
                else
                    tF = app.CurrentParentNodeText == uniqueparent(pp);
                    parenttree = app.TestFolderTree.Children(tF);
                end %if

                % Grab current elements in children tree
                if ~isempty(parenttree.Children)
                    app.CurrentChildrenNodeText = string({parenttree.Children.Text}');
                end %if

                inparent = find(contains(parentFolders, uniqueparent{pp}));
                for tt = 1:nnz(inparent)
                    if ~ismember(testList{inparent(tt)}, app.CurrentChildrenNodeText)
                        uitreenode(parenttree,'Text', testList{inparent(tt)});
                    end %if
                end %for
            end %for

            expand(app.TestFolderTree,'all')

        end %function

    end %methods


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            app.ResultsFolderEditField.Value = pwd;

        end

        % Button pushed function: TestFolderBrowseButton
        function onTestFolderBrowseButtonPushed(app, event)
            %onTestFolderBrowseButtonPushed

            testFolder = uigetdir(pwd,...
                'Select test folder location');
            resultFolder = app.ResultsFolderEditField.Value;

            try
                if ~isequal(testFolder, 0)

                    app.TVKBase = tvk.TVKBase(testFolder, resultFolder);
                    app.SelectAllTestsCBValue = 0;
                    app.SelectedNodes = [];
                    app.TVKBase.selectTests("none");

                    if isempty(ver('rptgen'))
                        app.TVKBase.EnableCustomReportGen = false;
                    end

                    %Call update to synchronize data model with UI
                    app.doWholeUpdate = true;
                    app.update();
                end
            catch
                uialert(app.UIFigure,...
                    ["Error finding tests in test folder."...
                    "Please select valid test folder."], "Test Folder Error");
            end

            drawnow
            figure(app.UIFigure)
            drawnow

        end

        % Button pushed function: ResultsFolderBrowseButton
        function onResultsFolderBrowseButtonPushed(app, event)
            %onResultsFolderBrowseButtonPushed

            resultFolder = uigetdir(pwd,...
                'Select report output folder location');

            if ~isequal(resultFolder, 0)
                app.TVKBase.ResultsFolder = resultFolder;
            end

            %Call update to synchronize data model with UI
            app.doWholeUpdate = false;
            app.update()

            drawnow
            figure(app.UIFigure)
            drawnow

        end

        % Value changed function: SelectAllTestsCheckBox
        function onSelectAllTestsCheckBoxValueChanged(app, event)
            value = app.SelectAllTestsCheckBox.Value;

            if value
                app.TVKBase.selectTests("all");

                % Recursively find all nodes in tree and set SelectedNodes
                % property
                for ii = 1:numel(app.TestFolderTree.Children)
                    app.SelectedNodes = [app.SelectedNodes; app.findAllNodes(app.TestFolderTree.Children(ii))];
                end
            else
                app.SelectedNodes = [];
                app.TVKBase.selectTests("none");
            end

            app.SelectAllTestsCBValue = value;

            %Call update to synchronize data model with UI
            app.doWholeUpdate = false;
            app.update();

        end

        % Selection changed function: TestFolderTree
        function onTestFolderTreeSelectionChanged(app, event)
            selectedNodes = app.TestFolderTree.SelectedNodes;
            app.SelectAllTestsCBValue = 0;

            % Check if parent node is selected
            parentIdx = ismember(string({selectedNodes.Text}'), app.TVKBase.TestTable.ParentList);
            if any(parentIdx)
                parentNodes = selectedNodes(parentIdx);
                for ii = 1:numel(parentNodes)
                    app.SelectedNodes = [app.SelectedNodes; app.findAllNodes(selectedNodes(ii))];
                end
            else
                app.SelectedNodes = selectedNodes;
            end

            testNames = string({app.SelectedNodes.Text}');
            tF = ~ismember(testNames, app.TVKBase.TestTable.TestList);
            testNames(tF) = [];

            app.TVKBase.selectTests(testNames);

            %Call update to synchronize data model with UI
            app.doWholeUpdate = false;
            app.update();

        end

        % Button pushed function: OpenSelectedTestsinEditorButton
        function onOpenSelectedTestsinEditorButtonPushed(app, event)

            tF = app.TVKBase.TestTable.Selected;
            selectedTestLocation = app.TVKBase.AllTestFiles(tF);

            for ii = 1:numel(selectedTestLocation)
                edit(selectedTestLocation(ii))
            end

        end

        % Value changed function: ReportFileEditField
        function onReportFileEditFieldValueChanged(app, event)
            value = app.ReportFileEditField.Value;

            if isempty(value)
                uialert(app.UIFigure,...
                    ["Error setting report file name."...
                    "Please enter a valid report name ending with "".docx""."], "Report File Error");
            end

            app.TVKBase.ReportFile = string(value);

            %Call update to synchronize data model with UI
            app.doWholeUpdate = false;
            app.update();

        end

        % Menu selected function: rptReporterAPI
        function onRptReporterAPIMenuSelected(app, event)
            app.TVKBase.EnableCustomReportGen = true;

            %Call update to synchronize data model with UI
            app.doWholeUpdate = false;
            app.update();

        end

        % Menu selected function: rptDefault
        function onRptDefaultMenuSelected(app, event)
            app.TVKBase.EnableCustomReportGen = false;

            %Call update to synchronize data model with UI
            app.doWholeUpdate = false;
            app.update();
            
        end

        % Menu selected function: CreateNewTestMenu
        function onCreateNewTestMenuSelected(app, event)

            [file, path, idx] = uiputfile(fullfile(app.TVKBase.TestFolder,'*.m'));

            try
                if idx

                    % Create new test class template
                    createNewTest(file, path);

                    % Create new test suite
                    app.TVKBase.createTestSuite();

                    %Call update to synchronize data model with UI
                    app.doWholeUpdate = true;
                    app.update();
                end
            catch
                uialert(app.UIFigure, "Test Creation Failed, please try again.", ...
                    "Error Creating New Test.")
            end

            drawnow
            figure(app.UIFigure)
            drawnow

        end

        % Menu selected function: OpenUserGuideMenu
        function onOpenUserGuideMenuSelected(app, event)

            guideLocation = which("Tool Validation Kit App Guide.pdf");
            disp("Path to Tool Validation Kit App Guide.pdf: " + guideLocation)
            open(guideLocation);

        end

        % Menu selected function: OpenQualityStatementsMenu
        function onOpenQualityStatementsMenuSelected(app, event)

            mlQualLocation = which("MATLAB-Quality-Statement.pdf");
            slQualLocation = which("Simulink-Quality-Statement.pdf");

            disp("Path to MATLAB-Quality-Statement.pdf: " + mlQualLocation)
            disp("Path to Simulink-Quality-Statement.pdf: " + slQualLocation)

            open(mlQualLocation);
            open(slQualLocation);

        end

        % Button pushed function: RunTestsandGenerateReportButton
        function onRunTestsandGenerateReportButtonPushed(app, event)

            if app.TVKBase.SelectedTestFiles ~= ""

                %Run tests and generate report progress dialog
                dlg = uiprogressdlg(app.UIFigure,...
                    "Title","Report generation in progress...",...
                    "Message","Running selected tests and generating report...",...
                    "Cancelable",false, ...
                    'Indeterminate','on');

                try
                    app.TVKBase.runTests();
                    app.TVKBase.generateReport();
                catch
                    uialert(app.UIFigure,...
                        ["Error running tests and generating report."...
                        "Please try running tests again."], "Run Report Error");
                end

                dlg.Message = "Finished!";

                %Close dialog
                dlg.close();

            else

                uialert(app.UIFigure,...
                        ["Error running tests and generating report."...
                        "Please select a test file."], "Run Report Error");
            end


        end

        % Menu selected function: SaveSessionMenu
        function onSaveSessionMenuSelected(app, event)
            
            [file, path, idx] = uiputfile(fullfile(pwd,'*.mat'));

            try
                if idx
                    filename = fullfile(path, file);
                    sessionModel = app.TVKBase;
                    nodes = app.SelectedNodes;
                    selectAllCB = app.SelectAllTestsCBValue;
                    save(filename, "sessionModel", "nodes", "selectAllCB");
                end
            catch
                uialert(app.UIFigure, "Save session failed, please try again.", ...
                    "Error Saving Session.")
            end

            drawnow
            figure(app.UIFigure)
            drawnow

        end

        % Menu selected function: OpenSessionMenu
        function onOpenSessionMenuSelected(app, event)
            
            [file, path, idx] = uigetfile(fullfile(pwd,'*.mat'));

            try
                if idx
                    filename = fullfile(path, file);
                    contents = load(filename);

                    app.TVKBase = tvk.TVKBase(contents.sessionModel.TestFolder, ...
                        contents.sessionModel.ResultsFolder);

                    if isempty(app.TestFolderTree.Children)
                        app.updateTree();
                    end

                    oldNodes = contents.nodes;
                    oldNodesText = string({oldNodes.Text}');

                    allNodes = [];
                    for ii = 1:numel(app.TestFolderTree.Children)
                        allNodes = [allNodes; app.findAllNodes(app.TestFolderTree.Children(ii))];
                    end
                    allNodesText = string({allNodes.Text}');

                    tF = ismember(allNodesText, oldNodesText);

                    app.SelectedNodes = allNodes(tF);
                    app.SelectAllTestsCBValue = contents.selectAllCB;

                    %Call update to synchronize data model with UI
                    app.doWholeUpdate = true;
                    app.update();

                end %if
            catch
                uialert(app.UIFigure, "Load session failed, please try again.", ...
                    "Error Loading Session.")
            end %try/catch

            drawnow
            figure(app.UIFigure)
            drawnow

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SessionOptionsMenu = uimenu(app.UIFigure);
            app.SessionOptionsMenu.Text = 'Session Options';
            app.OpenSessionMenu = uimenu(app.SessionOptionsMenu);
            app.OpenSessionMenu.MenuSelectedFcn = createCallbackFcn(app, @onOpenSessionMenuSelected, true);
            app.OpenSessionMenu.Text = 'Open Session';
            app.SaveSessionMenu = uimenu(app.SessionOptionsMenu);
            app.SaveSessionMenu.MenuSelectedFcn = createCallbackFcn(app, @onSaveSessionMenuSelected, true);
            app.SaveSessionMenu.Enable = 'off';
            app.SaveSessionMenu.Text = 'Save Session';
            app.ReportOptionsMenu = uimenu(app.UIFigure);
            app.ReportOptionsMenu.Text = 'Report Options';
            app.ReportGeneratorOptionsMenu = uimenu(app.ReportOptionsMenu);
            app.ReportGeneratorOptionsMenu.Enable = 'off';
            app.ReportGeneratorOptionsMenu.Text = 'Report Generator Options';
            app.rptReporterAPI = uimenu(app.ReportGeneratorOptionsMenu);
            app.rptReporterAPI.MenuSelectedFcn = createCallbackFcn(app, @onRptReporterAPIMenuSelected, true);
            app.rptReporterAPI.Enable = 'off';
            app.rptReporterAPI.Accelerator = '1';
            app.rptReporterAPI.Text = 'MATLAB Report Generator';
            app.rptDefault = uimenu(app.ReportGeneratorOptionsMenu);
            app.rptDefault.MenuSelectedFcn = createCallbackFcn(app, @onRptDefaultMenuSelected, true);
            app.rptDefault.Enable = 'off';
            app.rptDefault.Accelerator = '0';
            app.rptDefault.Text = 'Test Report Plugin (Core MATLAB)';
            app.TestOptionsMenu = uimenu(app.UIFigure);
            app.TestOptionsMenu.Text = 'Test Options';
            app.CreateNewTestMenu = uimenu(app.TestOptionsMenu);
            app.CreateNewTestMenu.MenuSelectedFcn = createCallbackFcn(app, @onCreateNewTestMenuSelected, true);
            app.CreateNewTestMenu.Enable = 'off';
            app.CreateNewTestMenu.Text = 'Create New Test';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.OpenUserGuideMenu = uimenu(app.HelpMenu);
            app.OpenUserGuideMenu.MenuSelectedFcn = createCallbackFcn(app, @onOpenUserGuideMenuSelected, true);
            app.OpenUserGuideMenu.Text = 'Open User Guide';
            app.OpenQualityStatementsMenu = uimenu(app.HelpMenu);
            app.OpenQualityStatementsMenu.MenuSelectedFcn = createCallbackFcn(app, @onOpenQualityStatementsMenuSelected, true);
            app.OpenQualityStatementsMenu.Text = 'Open Quality Statements';
            app.TestSelectionPanel = uipanel(app.UIFigure);
            app.TestSelectionPanel.Title = 'Test Selection';
            app.TestFolderEditFieldLabel = uilabel(app.TestSelectionPanel);
            app.TestFolderEditFieldLabel.Text = 'Test Folder:';
            app.TestFolderEditField = uieditfield(app.TestSelectionPanel, 'text');
            app.TestFolderEditField.Editable = 'off';
            app.TestFolderEditField.Value = 'Select Test Folder';
            app.TestFolderBrowseButton = uibutton(app.TestSelectionPanel, 'push');
            app.TestFolderBrowseButton.ButtonPushedFcn = createCallbackFcn(app, @onTestFolderBrowseButtonPushed, true);
            app.TestFolderBrowseButton.Text = 'Browse';
            app.TestFolderTree = uitree(app.TestSelectionPanel);
            app.TestFolderTree.Multiselect = 'on';
            app.TestFolderTree.SelectionChangedFcn = createCallbackFcn(app, @onTestFolderTreeSelectionChanged, true);
            app.SelectAllTestsCheckBox = uicheckbox(app.TestSelectionPanel);
            app.SelectAllTestsCheckBox.ValueChangedFcn = createCallbackFcn(app, @onSelectAllTestsCheckBoxValueChanged, true);
            app.SelectAllTestsCheckBox.Text = 'Select All Tests';
            app.OpenSelectedTestsinEditorButton = uibutton(app.TestSelectionPanel, 'push');
            app.OpenSelectedTestsinEditorButton.ButtonPushedFcn = createCallbackFcn(app, @onOpenSelectedTestsinEditorButtonPushed, true);
            app.OpenSelectedTestsinEditorButton.Enable = 'off';
            app.OpenSelectedTestsinEditorButton.Text = 'Open Selected Test(s) in Editor';
            app.ResultsFolderEditFieldLabel = uilabel(app.TestSelectionPanel);
            app.ResultsFolderEditFieldLabel.Enable = 'off';
            app.ResultsFolderEditFieldLabel.Text = 'Results Folder:';
            app.ResultsFolderEditField = uieditfield(app.TestSelectionPanel, 'text');
            app.ResultsFolderEditField.Editable = 'off';
            app.ResultsFolderEditField.Enable = 'off';
            app.ResultsFolderEditField.Value = 'Select Report Output Folder';
            app.ResultsFolderBrowseButton = uibutton(app.TestSelectionPanel, 'push');
            app.ResultsFolderBrowseButton.ButtonPushedFcn = createCallbackFcn(app, @onResultsFolderBrowseButtonPushed, true);
            app.ResultsFolderBrowseButton.Enable = 'off';
            app.ResultsFolderBrowseButton.Text = 'Browse';
            app.RunReportPanel = uipanel(app.UIFigure);
            app.RunReportPanel.Title = 'Run Report';
            app.SelectedTestsTable = uitable(app.RunReportPanel);
            app.SelectedTestsTable.ColumnName = {'Test'; 'Test Class'; 'Test Folder'};
            app.SelectedTestsTable.RowName = {};
            app.ReportFileEditFieldLabel = uilabel(app.RunReportPanel);
            app.ReportFileEditFieldLabel.Text = 'Report File:';
            app.ReportFileEditField = uieditfield(app.RunReportPanel, 'text');
            app.ReportFileEditField.ValueChangedFcn = createCallbackFcn(app, @onReportFileEditFieldValueChanged, true);
            app.RunTestsandGenerateReportButton = uibutton(app.RunReportPanel, 'push');
            app.RunTestsandGenerateReportButton.ButtonPushedFcn = createCallbackFcn(app, @onRunTestsandGenerateReportButtonPushed, true);
            app.RunTestsandGenerateReportButton.Enable = 'off';
            app.RunTestsandGenerateReportButton.Text = 'Run Tests and Generate Report';
            app.SelectedReportGeneratorOptionEditFieldLabel = uilabel(app.RunReportPanel);
            app.SelectedReportGeneratorOptionEditFieldLabel.Text = 'Selected Report Generator Option:';
            app.SelectedReportGeneratorOptionEditField = uieditfield(app.RunReportPanel, 'text');
            app.SelectedReportGeneratorOptionEditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TVKApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end